classdef AFM_Image_Analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AFMImageAnalysisScriptUIFigure  matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        EXPORTButton                    matlab.ui.control.Button
        COMPUTEButton                   matlab.ui.control.Button
        fileButton                      matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        LabeledFlakesTab                matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        LabeledFlakesNumberedTab        matlab.ui.container.Tab
        UIAxes2                         matlab.ui.control.UIAxes
        FlakeDataTab                    matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
        EdgeDetectionThresholdEditField  matlab.ui.control.NumericEditField
        EdgeDetectionThresholdEditFieldLabel  matlab.ui.control.Label
        AdaptiveFilterRadiusEditField   matlab.ui.control.NumericEditField
        AdaptiveFilterRadiusEditFieldLabel  matlab.ui.control.Label
        EdgeDilationRadiusEditField     matlab.ui.control.NumericEditField
        EdgeDilationRadiusEditFieldLabel  matlab.ui.control.Label
        EdgeDetectionMethodListBox      matlab.ui.control.ListBox
        EdgeDetectionMethodListBoxLabel  matlab.ui.control.Label
        IncreaseContrastSwitch          matlab.ui.control.Switch
        IncreaseContrastSwitchLabel     matlab.ui.control.Label
        AvgFlakeAreanm2EditField        matlab.ui.control.NumericEditField
        AvgFlakeAreanm2EditFieldLabel   matlab.ui.control.Label
        AvgFlakeLengthnmEditField       matlab.ui.control.NumericEditField
        AvgFlakeLengthnmEditFieldLabel  matlab.ui.control.Label
        AvgMAXthicknessnmEditField      matlab.ui.control.NumericEditField
        AvgMAXthicknessnmEditFieldLabel  matlab.ui.control.Label
        AvgMEANthicknessnmEditField     matlab.ui.control.NumericEditField
        AvgMEANthicknessnmEditFieldLabel  matlab.ui.control.Label
        AvgFlakeVolumenm3EditField      matlab.ui.control.NumericEditField
        AvgFlakeVolumenm3EditFieldLabel  matlab.ui.control.Label
        BusyIndicatorLamp               matlab.ui.control.Lamp
        BusyIndicatorLampLabel          matlab.ui.control.Label
        AvgMEDthicknessnmEditField      matlab.ui.control.NumericEditField
        AvgMEDthicknessnmEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        filePath = 0; % Defaults to 0, since uigetfile defaults to 0
        parentFolder = 0;
        fileName = 0;
        flakeInfo = [];
        labeledFlakes = [];
        contrast = false; % Whether to increase image contrast
        edgeMethod = 'canny';
        fudgeFactor = 1;
        filterRadius = 5;
        dilateRadius = 5;
        
        % Keeps track if image computation is complete
        processed = false;
        
        % Overall Flake Properties
        avgLength = 0;
        avgArea = 0;
        avgVol = 0;
        avgMaxThick = 0;
        avgMeanThick = 0;
    end
    
    methods (Access = private)
        
        function scaleImage(app, ui, image)
            set(ui, 'Units', 'Pixels')
            % Fill figure with axes and remove tick labels
            % ui.Position = [0 0 app.AFMImageAnalysisScriptUIFigure.Position(3:4)];
            
            % Remove title, axis labels, and tick labels
            title(ui, []);
            xlabel(ui, []);
            ylabel(ui, []);
            ui.XAxis.TickLabels = {};
            ui.YAxis.TickLabels = {};
            % Display image and stretch to fill axes
%             I = imshow(image, 'Parent', ui, ...
%                 'XData', [1 ui.Position(3)], ...
%                 'YData', [1 ui.Position(4)]);
            I = imshow(image, 'Parent', ui);            

            % Set limits of axes
            ui.XLim = [0 I.XData(2)];
            ui.YLim = [0 I.YData(2)];
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: EdgeDetectionMethodListBox
        function EdgeDetectionMethodListBoxValueChanged(app, event)
            value = app.EdgeDetectionMethodListBox.Value;
            app.edgeMethod = value;
        end

        % Button pushed function: fileButton
        function fileButtonPushed(app, event)
            app.processed = false;
            [fileNameLoc,path] = uigetfile('../*.ibw'); %ibw files only
            % Force return to GUI
            figure(app.AFMImageAnalysisScriptUIFigure);
            app.filePath = strcat(path, fileNameLoc);
            app.parentFolder = path;
            app.fileName = fileNameLoc;
        end

        % Value changed function: IncreaseContrastSwitch
        function IncreaseContrastSwitchValueChanged(app, event)
            app.contrast = ~app.contrast; % Toggles value
        end

        % Value changed function: EdgeDetectionThresholdEditField
        function EdgeDetectionThresholdEditFieldValueChanged(app, event)
            value = app.EdgeDetectionThresholdEditField.Value;
            app.fudgeFactor = value;
        end

        % Value changed function: AdaptiveFilterRadiusEditField
        function AdaptiveFilterRadiusEditFieldValueChanged(app, event)
            value = app.AdaptiveFilterRadiusEditField.Value;
            app.filterRadius = value;
        end

        % Value changed function: EdgeDilationRadiusEditField
        function EdgeDilationRadiusEditFieldValueChanged(app, event)
            value = app.EdgeDilationRadiusEditField.Value;
            app.dilateRadius = value;
        end

        % Button pushed function: COMPUTEButton
        function COMPUTEButtonPushed(app, event)
            if app.filePath == 0
                uiwait(warndlg('Please select a File', 'Error'));
                return;
            else
            
                % Turns on calculating lamp
                app.BusyIndicatorLamp.Color = 'red';
                
                % Calls the function with our inputs
                afmResults = getAFMFlakes(app.filePath, app.contrast, app.edgeMethod,...
                    app.fudgeFactor, app.filterRadius, app.dilateRadius);
                
                % Gets are updates the Mean values
                flakeInfoLoc = afmResults.flakeInfo;
                app.flakeInfo = flakeInfoLoc;
                app.AvgFlakeAreanm2EditField.Value = mean(flakeInfoLoc(:,1));
                app.AvgFlakeLengthnmEditField.Value = mean(flakeInfoLoc(:,4));
                app.AvgMAXthicknessnmEditField.Value = mean(flakeInfoLoc(:,2));
                app.AvgMEANthicknessnmEditField.Value = mean(flakeInfoLoc(:,3));
                app.AvgFlakeVolumenm3EditField.Value = mean(flakeInfoLoc(:,5));
                app.AvgMEDthicknessnmEditField.Value = mean(flakeInfoLoc(:,6));
                
                % Updates flake image
                scaleImage(app, app.UIAxes, afmResults.labeledFlakes);
                
                % Updates labeled flake image
                app.labeledFlakes = afmResults.labeledFlakesNum;
                scaleImage(app, app.UIAxes2, afmResults.labeledFlakesNum);
                
                % Updates the table
                app.UITable.Data = []; % Clears table
                app.UITable.Data(:,1) = 1:size(flakeInfoLoc,1);
                app.UITable.Data(:,[2:7]) = flakeInfoLoc(:,[2 3 6 4 1 5]);
                
                app.processed = true;
                % Turns on calculating lamp
                app.BusyIndicatorLamp.Color = 'k';
            end
            
            
        end

        % Callback function
        function LabeledFlakesTabSizeChanged(app, event)
            
        end

        % Callback function
        function LabeledFlakesNumberedTabSizeChanged(app, event)
            
        end

        % Button pushed function: EXPORTButton
        function EXPORTButtonPushed(app, event)
            if app.processed
                % make data directory
                mkdir(app.parentFolder, strcat(app.fileName,' Data'));
                oldFold = cd(strcat(app.parentFolder,'\',app.fileName,' Data'));
                
                % Saves Flake data table
                % Builds table
                flake = [1:size(app.flakeInfo,1)]';
                Max_Thickness = app.flakeInfo(:,2);
                Mean_Thickness = app.flakeInfo(:,3);
                length = app.flakeInfo(:,4);
                area = app.flakeInfo(:,1);
                volume = app.flakeInfo(:,5);
                Med_Thickness = app.flakeInfo(:,6);

                flakeInfoTable = table(flake, Max_Thickness, Mean_Thickness, Med_Thickness,length, area, volume);
                writetable(flakeInfoTable, strcat(app.fileName, '.csv'));
                
                % Save Labeled Image file
                imwrite(app.labeledFlakes, strcat('LI_',app.fileName, '.png'));
                
                % Creates text file of Program parameters
                Increased_Contrast = sprintf('%d', app.contrast);
                Edge_Threshold = app.fudgeFactor;
                Filter_Radius = app.filterRadius;
                Dilate_Radius = app.dilateRadius;
                Detection_Method = app.edgeMethod;
                
                paraInfoTable = cell2table({Increased_Contrast, Edge_Threshold, Filter_Radius,...
                                            Dilate_Radius, Detection_Method});
                writetable(paraInfoTable, strcat(app.fileName, '_scan_config', '.csv'));
                
                % Returns to the old folder
                cd(oldFold);
            else
                uiwait(warndlg('Please COMPUTE File', 'Error'));
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AFMImageAnalysisScriptUIFigure = uifigure('Visible', 'off');
            app.AFMImageAnalysisScriptUIFigure.Name = 'AFM Image Analysis Script';
            app.AFMImageAnalysisScriptUIFigure.Icon = 'icon_48.png';
            app.GridLayout = uigridlayout(app.AFMImageAnalysisScriptUIFigure);
            app.EXPORTButton = uibutton(app.GridLayout, 'push');
            app.EXPORTButton.ButtonPushedFcn = createCallbackFcn(app, @EXPORTButtonPushed, true);
            app.EXPORTButton.Text = 'EXPORT';
            app.COMPUTEButton = uibutton(app.GridLayout, 'push');
            app.COMPUTEButton.ButtonPushedFcn = createCallbackFcn(app, @COMPUTEButtonPushed, true);
            app.COMPUTEButton.Text = 'COMPUTE';
            app.fileButton = uibutton(app.GridLayout, 'push');
            app.fileButton.ButtonPushedFcn = createCallbackFcn(app, @fileButtonPushed, true);
            app.fileButton.Text = 'File';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.LabeledFlakesTab = uitab(app.TabGroup);
            app.LabeledFlakesTab.Title = 'Labeled Flakes';
            app.UIAxes = uiaxes(app.LabeledFlakesTab);
            app.LabeledFlakesNumberedTab = uitab(app.TabGroup);
            app.LabeledFlakesNumberedTab.Title = 'Labeled Flakes (Numbered)';
            app.UIAxes2 = uiaxes(app.LabeledFlakesNumberedTab);
            app.FlakeDataTab = uitab(app.TabGroup);
            app.FlakeDataTab.Title = 'Flake Data';
            app.UITable = uitable(app.FlakeDataTab);
            app.UITable.ColumnName = {'Flake #'; 'Max Thickness (nm)'; 'Mean Thickness (nm)'; 'Median Thickness (nm)'; 'Length (nm)'; 'Area (nm^2)'; 'Volume (nm^3)'};
            app.UITable.RowName = {};
            app.EdgeDetectionThresholdEditField = uieditfield(app.GridLayout, 'numeric');
            app.EdgeDetectionThresholdEditField.Limits = [0 10];
            app.EdgeDetectionThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @EdgeDetectionThresholdEditFieldValueChanged, true);
            app.EdgeDetectionThresholdEditField.Value = 1;
            app.EdgeDetectionThresholdEditFieldLabel = uilabel(app.GridLayout);
            app.EdgeDetectionThresholdEditFieldLabel.Text = 'Edge Detection Threshold';
            app.AdaptiveFilterRadiusEditField = uieditfield(app.GridLayout, 'numeric');
            app.AdaptiveFilterRadiusEditField.Limits = [1 50];
            app.AdaptiveFilterRadiusEditField.ValueChangedFcn = createCallbackFcn(app, @AdaptiveFilterRadiusEditFieldValueChanged, true);
            app.AdaptiveFilterRadiusEditField.Value = 10;
            app.AdaptiveFilterRadiusEditFieldLabel = uilabel(app.GridLayout);
            app.AdaptiveFilterRadiusEditFieldLabel.Text = 'Adaptive Filter Radius';
            app.EdgeDilationRadiusEditField = uieditfield(app.GridLayout, 'numeric');
            app.EdgeDilationRadiusEditField.Limits = [1 20];
            app.EdgeDilationRadiusEditField.ValueChangedFcn = createCallbackFcn(app, @EdgeDilationRadiusEditFieldValueChanged, true);
            app.EdgeDilationRadiusEditField.Value = 3;
            app.EdgeDilationRadiusEditFieldLabel = uilabel(app.GridLayout);
            app.EdgeDilationRadiusEditFieldLabel.Text = 'Edge Dilation Radius';
            app.EdgeDetectionMethodListBox = uilistbox(app.GridLayout);
            app.EdgeDetectionMethodListBox.Items = {'Sobel', 'Prewitt', 'Roberts', 'log', 'zerocross', 'canny', 'approxcanny'};
            app.EdgeDetectionMethodListBox.ValueChangedFcn = createCallbackFcn(app, @EdgeDetectionMethodListBoxValueChanged, true);
            app.EdgeDetectionMethodListBox.Value = 'canny';
            app.EdgeDetectionMethodListBoxLabel = uilabel(app.GridLayout);
            app.EdgeDetectionMethodListBoxLabel.Text = 'Edge Detection Method';
            app.IncreaseContrastSwitch = uiswitch(app.GridLayout, 'slider');
            app.IncreaseContrastSwitch.ValueChangedFcn = createCallbackFcn(app, @IncreaseContrastSwitchValueChanged, true);
            app.IncreaseContrastSwitchLabel = uilabel(app.GridLayout);
            app.IncreaseContrastSwitchLabel.Text = 'Increase Contrast';
            app.AvgFlakeAreanm2EditField = uieditfield(app.GridLayout, 'numeric');
            app.AvgFlakeAreanm2EditField.Editable = 'off';
            app.AvgFlakeAreanm2EditFieldLabel = uilabel(app.GridLayout);
            app.AvgFlakeAreanm2EditFieldLabel.Text = 'Avg. Flake Area (nm^2)';
            app.AvgFlakeLengthnmEditField = uieditfield(app.GridLayout, 'numeric');
            app.AvgFlakeLengthnmEditField.Editable = 'off';
            app.AvgFlakeLengthnmEditFieldLabel = uilabel(app.GridLayout);
            app.AvgFlakeLengthnmEditFieldLabel.Text = 'Avg. Flake Length(nm)';
            app.AvgMAXthicknessnmEditField = uieditfield(app.GridLayout, 'numeric');
            app.AvgMAXthicknessnmEditField.Editable = 'off';
            app.AvgMAXthicknessnmEditFieldLabel = uilabel(app.GridLayout);
            app.AvgMAXthicknessnmEditFieldLabel.Text = 'Avg. MAX thickness (nm)';
            app.AvgMEANthicknessnmEditField = uieditfield(app.GridLayout, 'numeric');
            app.AvgMEANthicknessnmEditField.Editable = 'off';
            app.AvgMEANthicknessnmEditFieldLabel = uilabel(app.GridLayout);
            app.AvgMEANthicknessnmEditFieldLabel.Text = 'Avg. MEAN thickness (nm)';
            app.AvgFlakeVolumenm3EditField = uieditfield(app.GridLayout, 'numeric');
            app.AvgFlakeVolumenm3EditField.Editable = 'off';
            app.AvgFlakeVolumenm3EditFieldLabel = uilabel(app.GridLayout);
            app.AvgFlakeVolumenm3EditFieldLabel.Text = 'Avg. Flake Volume (nm^3)';
            app.BusyIndicatorLamp = uilamp(app.GridLayout);
            app.BusyIndicatorLampLabel = uilabel(app.GridLayout);
            app.BusyIndicatorLampLabel.Text = 'Busy Indicator';
            app.AvgMEDthicknessnmEditField = uieditfield(app.GridLayout, 'numeric');
            app.AvgMEDthicknessnmEditField.Editable = 'off';
            app.AvgMEDthicknessnmEditFieldLabel = uilabel(app.GridLayout);
            app.AvgMEDthicknessnmEditFieldLabel.Text = 'Avg. MED thickness (nm)';
            % Show the figure after all components are created
            app.AFMImageAnalysisScriptUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AFM_Image_Analysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AFMImageAnalysisScriptUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AFMImageAnalysisScriptUIFigure)
        end
    end
end